classdef M_ISSION < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        M_ISSIONUIFigure           matlab.ui.Figure
        M_ISSIONLabel              matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        FactorcalculationTab       matlab.ui.container.Tab
        SIGMAPHIPanel              matlab.ui.container.Panel
        cutEditField_3             matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        XYZ                        matlab.ui.control.EditField
        ObservationFileLabel_14    matlab.ui.control.Label
        XYZ2                       matlab.ui.control.Button
        EditField_4                matlab.ui.control.EditField
        L2CheckBox                 matlab.ui.control.CheckBox
        L1CheckBox                 matlab.ui.control.CheckBox
        cutoff_freqEditField       matlab.ui.control.NumericEditField
        cutoff_freqEditFieldLabel  matlab.ui.control.Label
        sigmaphiButton             matlab.ui.control.Button
        ATX                        matlab.ui.control.Button
        Antenna                    matlab.ui.control.EditField
        ObservationFileLabel_3     matlab.ui.control.Label
        CLK                        matlab.ui.control.Button
        Clock                      matlab.ui.control.EditField
        ObservationFileLabel_2     matlab.ui.control.Label
        DIXSGPanel                 matlab.ui.container.Panel
        dlonEditField              matlab.ui.control.NumericEditField
        dlonEditFieldLabel         matlab.ui.control.Label
        lonEditField_2             matlab.ui.control.NumericEditField
        EditField_7Label           matlab.ui.control.Label
        lonEditField               matlab.ui.control.NumericEditField
        lonEditFieldLabel          matlab.ui.control.Label
        stepEditField              matlab.ui.control.NumericEditField
        stepEditFieldLabel         matlab.ui.control.Label
        firstEditField             matlab.ui.control.NumericEditField
        firstEditFieldLabel        matlab.ui.control.Label
        levelEditField             matlab.ui.control.NumericEditField
        levelEditFieldLabel        matlab.ui.control.Label
        dlatEditField              matlab.ui.control.NumericEditField
        dlatEditFieldLabel         matlab.ui.control.Label
        latEditField               matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        LatEditField               matlab.ui.control.NumericEditField
        LatEditFieldLabel          matlab.ui.control.Label
        EditField_3                matlab.ui.control.EditField
        DIXSGButton                matlab.ui.control.Button
        ROTIAATRPanel              matlab.ui.container.Panel
        EditField_2                matlab.ui.control.EditField
        ROTIButton                 matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        AATRButton                 matlab.ui.control.Button
        inputPanel                 matlab.ui.container.Panel
        BDSCheckBox                matlab.ui.control.CheckBox
        GALCheckBox                matlab.ui.control.CheckBox
        GLOCheckBox                matlab.ui.control.CheckBox
        GPSCheckBox                matlab.ui.control.CheckBox
        SystemSelectionLabel       matlab.ui.control.Label
        Sp3Button                  matlab.ui.control.Button
        Sp3                        matlab.ui.control.EditField
        ClockFileLabel             matlab.ui.control.Label
        slip                       matlab.ui.control.Switch
        CycleSlipDetectionLabel    matlab.ui.control.Label
        cutoffangle                matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        Observation                matlab.ui.control.EditField
        ObservationFileLabel       matlab.ui.control.Label
        ObservationButton          matlab.ui.control.Button
        ResultdrawingTab           matlab.ui.container.Tab
        plot_sateEditField         matlab.ui.control.EditField
        plot_sateEditFieldLabel    matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        diffsateTextArea           matlab.ui.control.TextArea
        diffsateTextAreaLabel      matlab.ui.control.Label
        plotfile                   matlab.ui.control.EditField
        ObservationFileLabel_4     matlab.ui.control.Label
        plotfileButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        comparativeanalysisTab     matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        MultisystemTab             matlab.ui.container.Tab
        CheckBox_4                 matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        CheckBox                   matlab.ui.control.CheckBox
        CLamp                      matlab.ui.control.Lamp
        CLampLabel                 matlab.ui.control.Label
        ELamp                      matlab.ui.control.Lamp
        ELampLabel                 matlab.ui.control.Label
        RLamp                      matlab.ui.control.Lamp
        RLampLabel                 matlab.ui.control.Label
        GLamp                      matlab.ui.control.Lamp
        GLampLabel                 matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        HEditField_2               matlab.ui.control.NumericEditField
        HEditField_2Label          matlab.ui.control.Label
        MEditField_2               matlab.ui.control.NumericEditField
        MEditFieldLabel_2          matlab.ui.control.Label
        MEditField                 matlab.ui.control.NumericEditField
        MEditFieldLabel            matlab.ui.control.Label
        HEditField                 matlab.ui.control.NumericEditField
        HEditFieldLabel            matlab.ui.control.Label
        plotfile_2                 matlab.ui.control.EditField
        ObservationFileLabel_5     matlab.ui.control.Label
        plotfileButton_2           matlab.ui.control.Button
        PlotButton_3               matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        UIAxes6                    matlab.ui.control.UIAxes
        MultipleindexTab           matlab.ui.container.Tab
        Button_4                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        plotfileButton_6           matlab.ui.control.Button
        plotfileButton_5           matlab.ui.control.Button
        plotfileButton_4           matlab.ui.control.Button
        plotfileButton_3           matlab.ui.control.Button
        plotfile_6                 matlab.ui.control.EditField
        ObservationFileLabel_9     matlab.ui.control.Label
        plotfile_5                 matlab.ui.control.EditField
        ObservationFileLabel_8     matlab.ui.control.Label
        plotfile_4                 matlab.ui.control.EditField
        ObservationFileLabel_7     matlab.ui.control.Label
        plotfile_3                 matlab.ui.control.EditField
        ObservationFileLabel_6     matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        UIAxes6_2                  matlab.ui.control.UIAxes
        PlotButton_2               matlab.ui.control.Button
        RegionaldataanalysisTab    matlab.ui.container.Tab
        DIXSGROTIregionaldataplottingPanel  matlab.ui.container.Panel
        Button_6                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        TheEditFieldLabel_2        matlab.ui.control.Label
        TheEditField               matlab.ui.control.NumericEditField
        TheEditFieldLabel          matlab.ui.control.Label
        ObservationButton_5        matlab.ui.control.Button
        Observation_5              matlab.ui.control.EditField
        ObservationFileLabel_13    matlab.ui.control.Label
        Observation_3              matlab.ui.control.EditField
        ObservationFileLabel_11    matlab.ui.control.Label
        ObservationButton_3        matlab.ui.control.Button
        PLOTButton                 matlab.ui.control.Button
        ROTIregionaldatacalculationPanel  matlab.ui.container.Panel
        Observation_2              matlab.ui.control.EditField
        ObservationFileLabel_10    matlab.ui.control.Label
        ObservationButton_2        matlab.ui.control.Button
        dlonEditField_2            matlab.ui.control.NumericEditField
        dlonEditField_2Label       matlab.ui.control.Label
        lonEditField_4             matlab.ui.control.NumericEditField
        EditField_7Label_2         matlab.ui.control.Label
        lonEditField_3             matlab.ui.control.NumericEditField
        lonEditField_3Label        matlab.ui.control.Label
        dlatEditField_2            matlab.ui.control.NumericEditField
        dlatEditField_2Label       matlab.ui.control.Label
        latEditField_2             matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        LatEditField_2             matlab.ui.control.NumericEditField
        LatEditField_2Label        matlab.ui.control.Label
        EditField_5                matlab.ui.control.EditField
        RUNButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
      plot_Mfilepath;
      plotroti_path;
      plotaatr_path;
      plotsigmaphi_path;
      plotrmsaatr_path;
      plotdixsg_path;
      plotRegROTI_path;
      slipnowvalue;
      sliplastvalue;
      Refresh;
      
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
           %Draw and compare multiple index files
            % Initialize an empty cell array to store file paths
            files_to_plot = {'' '' '' ''};

            % Check the path in the app structure and add it to files_to_plot (if not empty)
            if ~isempty(app.plotroti_path)
                files_to_plot{1} = app.plotroti_path;
            end
            if ~isempty(app.plotaatr_path)
                files_to_plot{2} = app.plotaatr_path;
            end
            if ~isempty(app.plotrmsaatr_path)
                files_to_plot{3} = app.plotrmsaatr_path;
            end
            if ~isempty(app.plotsigmaphi_path)
                files_to_plot{4} = app.plotsigmaphi_path;
            end


            if ~isempty(files_to_plot)
                plot_Mulfiles(app,files_to_plot);
            else
                app.plotfile_3.Value='No files available';
            end
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            %Draw a lot of system data
            if ~isempty(app.plot_Mfilepath)
                Mfilepath = app.plot_Mfilepath;
                plottime=[app.HEditField.Value,app.MEditField.Value,app.HEditField_2.Value,app.MEditField_2.Value];
                PLOTsys(1)=app.CheckBox.Value;
                PLOTsys(2)=app.CheckBox_2.Value;
                PLOTsys(3)=app.CheckBox_3.Value;
                PLOTsys(4)=app.CheckBox_4.Value;
                plot_Mfiles(app,Mfilepath,plottime,PLOTsys);
            end
            
            
        end

        % Button pushed function: plotfileButton_2
        function plotfileButton_2Pushed(app, event)
            %Select the generated multi system data file: M_
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a M_ROTI/M_AATR/M_RESAATR file');
            if ischar(filename)
                last_six = filename(end-9:end-4);
                if strcmp(last_six, 'M_ROTI')||strcmp(last_six, 'M_AATR')||strcmp(last_six, '_RAATR')
                    app.plot_Mfilepath = fullfile(filepath, filename);
                    app.plotfile_2.Editable="on";
                    app.plotfile_2.Value=filename;
                    app.plotfile_2.Editable='off';
                    GREC=sysdata(app.plot_Mfilepath);
                    if GREC(1)==1
                        app.GLamp.Color=[0 1 0];
                        app.CheckBox.Enable="on";
                    else
                        app.GLamp.Color=[1 0 0];
                        app.CheckBox.Enable="off";

                    end
                    if GREC(2)==1
                        app.RLamp.Color=[0 1 0];
                        app.CheckBox_2.Enable="on";
                    else
                        app.RLamp.Color=[1 0 0];
                        app.CheckBox_2.Enable="off";

                    end
                    if GREC(3)==1
                        app.ELamp.Color=[0 1 0];
                        app.CheckBox_3.Enable="on";
                    else
                        app.ELamp.Color=[1 0 0];
                        app.CheckBox_3.Enable="off";

                    end
                    if GREC(4)==1
                        app.CLamp.Color=[0 1 0];
                        app.CheckBox_4.Enable="on";
                    else
                        app.CLamp.Color=[1 0 0];
                        app.CheckBox_4.Enable="off";

                    end
                else
                    app.plotfile_2.Value='Type of error';
                end
                subplot(1, 1, 1,'parent',app.Panel);
            else
                return
            end
        end

        % Button pushed function: plotfileButton_3
        function plotfileButton_3Pushed(app, event)
            %Select a single ROTI data file
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a ROTI file');
            if ischar(filename)
                last_four = filename(end-7:end-4);
                if strcmp(last_four, 'ROTI')
                    app.plotroti_path = fullfile(filepath, filename);
                    app.plotfile_3.Editable="on";
                    app.plotfile_3.Value=filename;
                    app.plotfile_3.Editable='off';
                    subplot(1, 1, 1,'parent',app.Panel_2);
                else
                    app.plotfile_3.Value='Type of error';
                end
            else
                return
            end
        end

        % Button pushed function: plotfileButton_4
        function plotfileButton_4Pushed(app, event)
            %Select a single AATR data file
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a AATR file');
            if ischar(filename)
                last_four = filename(end-7:end-4);last_six = filename(end-9:end-4);
                if strcmp(last_four, 'AATR') && ~strcmp(last_six, 'MSAATR')
                    app.plotaatr_path = fullfile(filepath, filename);
                    app.plotfile_4.Editable="on";
                    app.plotfile_4.Value=filename;
                    app.plotfile_4.Editable='off';
                    subplot(1, 1, 1,'parent',app.Panel_2);
                else
                    app.plotfile_4.Value='Type of error';
                end
            else
                return
            end
        end

        % Button pushed function: plotfileButton_5
        function plotfileButton_5Pushed(app, event)
            %Select a single RMSAATR data file
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a RMSAATR file');
            if ischar(filename)
                last_six = filename(end-9:end-4);
                if strcmp(last_six, 'MSAATR')
                    app.plotrmsaatr_path = fullfile(filepath, filename);
                    app.plotfile_5.Editable="on";
                    app.plotfile_5.Value=filename;
                    app.plotfile_5.Editable='off';
                    subplot(1, 1, 1,'parent',app.Panel_2);
                else
                    app.plotfile_5.Value='Type of error';
                end
            else
                return
            end
        end

        % Button pushed function: plotfileButton_6
        function plotfileButton_6Pushed(app, event)
            %Select a single SIGMAPHI data file
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a SIGMAPHI file');
            if ischar(filename)
                last_four = filename(end-7:end-4);
                if strcmp(last_four, 'aphi')
                    app.plotsigmaphi_path = fullfile(filepath, filename);
                    app.plotfile_6.Editable="on";
                    app.plotfile_6.Value=filename;
                    app.plotfile_6.Editable='off';
                    subplot(1, 1, 1,'parent',app.Panel_2);
                else
                    app.plotfile_6.Value='Type of error';
                end
            else
                return
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Deselect ROTI file
            if ~isempty(app.plotroti_path)
                app.plotroti_path = '';
                app.plotfile_3.Editable="on";
                app.plotfile_3.Value='';
                app.plotfile_3.Editable='off';
                subplot(1, 1, 1,'parent',app.Panel_2);

            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %Deselect AATR file
            if ~isempty(app.plotaatr_path)
                app.plotaatr_path = '';
                app.plotfile_4.Editable="on";
                app.plotfile_4.Value='';
                app.plotfile_4.Editable='off';
                subplot(1, 1, 1,'parent',app.Panel_2);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %Deselect RMSAATR file
            if ~isempty(app.plotrmsaatr_path)
                app.plotrmsaatr_path = '';
                app.plotfile_5.Editable="on";
                app.plotfile_5.Value='';
                app.plotfile_5.Editable='off';
                subplot(1, 1, 1,'parent',app.Panel_2);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %Deselect SIGMAPHI file
            if ~isempty(app.plotsigmaphi_path)
                app.plotsigmaphi_path = '';
                app.plotfile_6.Editable="on";
                app.plotfile_6.Value='';
                app.plotfile_6.Editable='off';
                subplot(1, 1, 1,'parent',app.Panel_2);
            end
        end

        % Button pushed function: ObservationButton_2
        function ObservationButton_2Pushed(app, event)
            %Select the ROTI data folder generated by this software
            path= uigetdir('Select the Observation file');%Select folder
            if ischar(path)
                sumfile=dir(path);
                if length(sumfile)==2
                    msgbox('The folder is empty','Warn');
                else
                    folderPath= path;
                    app.Observation_2.Editable = 'on';
                    app.Observation_2.Value    = folderPath;
                    app.Observation_2.Editable = 'off';
                    return
                end
            else
                return
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %Click on the calculation area ROTI
            app.EditField_5.Value='-';
            minlon=app.lonEditField_3.Value;
            maxlon=app.lonEditField_4.Value;
            minlat=app.LatEditField_2.Value;
            maxlat=app.latEditField_2.Value;
            dlat=app.dlatEditField_2.Value;
            dlon=app.dlonEditField_2.Value;
            rotipath=app.Observation_2.Value;
            startTime = tic;
            lonlat = [minlat, maxlat, minlon, maxlon, dlat, dlon];
            get_RegionalROTI(rotipath,lonlat);
            elapsedTime = toc(startTime);
            app.EditField_5.Value = sprintf('%.2f s', elapsedTime);
        end

        % Button pushed function: ObservationButton_3
        function ObservationButton_3Pushed(app, event)
            %Select DIXSG file
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a DIXSG file');
            if ischar(filename)
                last_five = filename(end-8:end-4);
                if strcmp(last_five, 'DIXSG')
                    app.plotdixsg_path = fullfile(filepath, filename);
                    app.Observation_3.Editable="on";
                    app.Observation_3.Value=filename;
                    app.Observation_3.Editable='off';
                else
                    app.Observation_3.Value='Type of error';
                end

            else
                return
            end
        end

        % Button pushed function: ObservationButton_5
        function ObservationButton_5Pushed(app, event)
           %Select area ROTI file  
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a Regional ROTI file');
            if ischar(filename)
                last_four = filename(end-7:end-4);
                if strcmp(last_four, 'ROTI')
                    app.plotRegROTI_path = fullfile(filepath, filename);
                    app.Observation_5.Editable="on";
                    app.Observation_5.Value=filename;
                    app.Observation_5.Editable='off';
                else
                    app.Observation_5.Value='Type of error';
                end

            else
                return
            end
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            %Draw comparison area DIXSG and area ROTI data
            Regionalpath={'' ''};
            if ~isempty(app.plotdixsg_path)
                Regionalpath{1}=app.plotdixsg_path;
            end
            if ~isempty(app.plotRegROTI_path)
                Regionalpath{2}=app.plotRegROTI_path;
            end
            if ~isempty(Regionalpath)
            hour=app.TheEditField.Value;
            plotRegional(Regionalpath,hour);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
           %Deselect area ROTI file
            if ~isempty(app.plotdixsg_path)
                   app.plotdixsg_path = '';
                    app.Observation_3.Editable="on";
                    app.Observation_3.Value='';
                    app.Observation_3.Editable='off';
           end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %
            if ~isempty(app.plotRegROTI_path)
                    app.plotRegROTI_path = '';
                    app.Observation_5.Editable="on";
                    app.Observation_5.Value='';
                    app.Observation_5.Editable='off';
           end
        end

        % Button pushed function: ObservationButton
        function ObservationButtonPushed(app, event)
            % Select the folder for observation files
            path= uigetdir('Select the Observation file');%Select folder
            if ischar(path)
                sumfile=dir(path);
                if length(sumfile)==2
                    msgbox('The folder is empty','Warn');
                else
                    folderPath= path;
                    app.Observation.Editable = 'on';
                    app.Observation.Value    = folderPath;
                    app.Observation.Editable = 'off';
                    return
                end
            else
                return
            end
        end

        % Button pushed function: Sp3Button
        function Sp3ButtonPushed(app, event)
            %Select folder for SP3 files: Only SP3 files from the previous day, current day, and next day can exist
            sp3path = uigetdir('Select the sp3 file');
            if ischar(sp3path)
                sumfile=dir(sp3path);
                if length(sumfile)==2
                    msgbox('The folder is empty','Warn');
                else
                    app.Sp3.Editable      = 'on';
                    app.Sp3.Value         = sp3path;
                    app.Sp3.Editable      = 'off';
                    return
                end
            else
                return
            end
        end

        % Button pushed function: ROTIButton
        function ROTIButtonPushed(app, event)
            %Calculate ROTI
            app.EditField_2.Value='-';
            startTime = tic;
            sys(1)=app.GPSCheckBox.Value;
            sys(2)=app.GLOCheckBox.Value;
            sys(3)=app.GALCheckBox.Value;
            sys(4)=app.BDSCheckBox.Value;
            folderPath=app.Observation.Value; %The folder path of the observation file
            r_ipath= folderPath;
            grandPath=fileparts(fileparts(folderPath));
            lim=app.cutoffangle.Value; % Cut off angle degree
            load('sate_mark.mat'); % Load star symbol
            newDirectoryName = 'raw_OBS';
            r_opath= fullfile(grandPath, newDirectoryName); % OBS storage path
            if exist(r_opath,'dir')==0
                mkdir(r_opath);
            end
            [Sites_Info]=R_rinex(r_ipath,r_opath); % Read observation file
            s_ipath=app.Sp3.Value; % The folder path of SP3 files
            newDirectoryName = 'raw_SP3';
            s_opath= fullfile(grandPath, newDirectoryName); % SP3 reads storage path
            read_sp3(s_ipath,s_opath,sate_mark); % Read SP3
            Get_cutobsE(r_opath,s_opath,Sites_Info,lim*pi/180,sate_mark);
            slipvalue=app.slip.Value; % cycle slip detection
            if slipvalue == "on"
                cut_slip_repair(r_opath,Sites_Info);
            end
            newname='raw_OBS_cut';
            r_cutopath=fullfile(grandPath,newname);
            Get_L_ROTI(r_cutopath,Sites_Info,sys); % ROTI
            elapsedTime = toc(startTime);
            app.EditField_2.Value = sprintf('%.2f s', elapsedTime);
        end

        % Button pushed function: AATRButton
        function AATRButtonPushed(app, event)
            %Calculate AATR
            app.EditField.Value='-';
            folderPath=app.Observation.Value; %The folder path of the observation file
            sys(1)=app.GPSCheckBox.Value;
            sys(2)=app.GLOCheckBox.Value;
            sys(3)=app.GALCheckBox.Value;
            sys(4)=app.BDSCheckBox.Value;
            r_ipath= folderPath;
            grandPath=fileparts(fileparts(folderPath));
            lim=app.cutoffangle.Value; %Cut off angle degree
            startTime = tic;
            load('sate_mark.mat'); % Load star symbol
            newDirectoryName = 'raw_OBS';
            r_opath= fullfile(grandPath, newDirectoryName); % OBS storage path
            if exist(r_opath,'dir')==0
                mkdir(r_opath);
            end
            [Sites_Info]=R_rinex(r_ipath,r_opath); % Read observation file
            s_ipath=app.Sp3.Value; % The folder path of SP3 files
            newDirectoryName = 'raw_SP3';
            s_opath= fullfile(grandPath, newDirectoryName); % SP3 reads storage path
            read_sp3(s_ipath,s_opath,sate_mark); % Read SP3

            Get_cutobsE(r_opath,s_opath,Sites_Info,lim*pi/180,sate_mark); % Remove data below the cutoff angle
            slipvalue=app.slip.Value; % cycle slip detection
            if slipvalue == "on"
                cut_slip_repair(r_opath,Sites_Info);
            end
            newname='raw_OBS_cut';
            r_cutopath=fullfile(grandPath,newname);
            Get_AATR(r_cutopath,Sites_Info,sys); 
            elapsedTime = toc(startTime);
            app.EditField.Value = sprintf('%.2f s', elapsedTime);
        end

        % Button pushed function: DIXSGButton
        function DIXSGButtonPushed(app, event)
            %Calculate DIXSG
            app.EditField_3.Value='-';
            folderPath=app.Observation.Value; %The folder path of the observation file
            sys(1)=app.GPSCheckBox.Value;
            sys(2)=app.GLOCheckBox.Value;
            sys(3)=app.GALCheckBox.Value;
            sys(4)=app.BDSCheckBox.Value;
            r_ipath= folderPath;
            grandPath=fileparts(fileparts(folderPath));
            lim=app.cutoffangle.Value; %Cut off angle degree
            load('sate_mark.mat'); % Load star symbol
            newDirectoryName = 'raw_OBS';
            r_opath= fullfile(grandPath, newDirectoryName); 
            if exist(r_opath,'dir')==0
                mkdir(r_opath);
            end
            [Sites_Info]=R_rinex(r_ipath,r_opath);
            startTime = tic;
            s_ipath=app.Sp3.Value; % The folder path of SP3 files
            newDirectoryName = 'raw_SP3';
            s_opath= fullfile(grandPath, newDirectoryName);
            read_sp3(s_ipath,s_opath,sate_mark); 
            Get_cutobsE(r_opath,s_opath,Sites_Info,lim*pi/180,sate_mark); % Remove data below the cutoff angle
            cut_slip_repair(r_opath,Sites_Info);
            newname='raw_OBS_cut';
            r_cutopath=fullfile(grandPath,newname);
            Get_cROT(r_cutopath,Sites_Info,sys); 
            first = app.firstEditField.Value;
            step = app.stepEditField.Value;
            lev = app.levelEditField.Value;
            minlon=app.lonEditField.Value;
            maxlon=app.lonEditField_2.Value;
            minlat=app.LatEditField.Value;
            maxlat=app.latEditField.Value;
            dlat=app.dlatEditField.Value;
            dlon=app.dlonEditField.Value;
            Get_DIXSG(grandPath,Sites_Info,lev,first,step,minlon,minlat,maxlon,maxlat,dlat,dlon,sys);
            elapsedTime = toc(startTime);
            app.EditField_3.Value = sprintf('%.2f s', elapsedTime);
        end

        % Button pushed function: CLK
        function CLKButtonPushed(app, event)
            %CLK file path
            [file,path] = uigetfile('*.clk*','Select the Clock file');
            if ischar(file)
                clock = fullfile(path,file);
                app.Clock.Editable      = 'on';
                app.Clock.Value         = clock;
                app.Clock.Editable      = 'off';
            else
                return
            end
        end

        % Button pushed function: ATX
        function ATXButtonPushed(app, event)
             %ATK file path
            [file,path] = uigetfile('*.atx','Select the Antenna file');
            if ischar(file)
                anten = fullfile(path,file);
                app.Antenna.Editable    = 'on';
                app.Antenna.Value       = anten;
                app.Antenna.Editable    = 'off';
            else
                return
            end
        end

        % Button pushed function: sigmaphiButton
        function sigmaphiButtonPushed(app, event)
            %Calculate sigmaphi
            app.EditField_4.Value='-';
            folderPath=app.Observation.Value;  %The folder path of the observation file
            s_ipath=app.Sp3.Value; % The folder path of SP3 files
            r_ipath= folderPath;
            list_obs1=dir([r_ipath '/*.rnx']);
            list_obs2=dir([r_ipath '/*.*o']);
            list_obs=[list_obs1;list_obs2];
            list_sp3=dir([s_ipath '/*.sp3']);
            len=length(list_sp3);
            if len<3
                error('need at least three SP3 files !!!');
            end
            for i=1:len-2
                pr_obs=[s_ipath '/' list_sp3(i).name];
                cu_obs=[s_ipath '/' list_sp3(i+1).name];
                nx_obs=[s_ipath '/' list_sp3(i+2).name];
            end
            files.orbitb = pr_obs;

            files.orbit  = cu_obs;
            files.orbita = nx_obs;
            files.anten  = app.Antenna.Value;
            files.clock  = app.Clock.Value;
            files.XYZ  = app.XYZ.Value;
            files.dcb    = 0;
            if app.Clock.Value~=0
                options.clock      = 'Clock File';
            else
                options.clock      = 'Sp3 File';
            end
            options.dcb = 0;
            options.system.gps = app.GPSCheckBox.Value;
            options.system.glo = app.GLOCheckBox.Value;
            options.system.gal = app.GALCheckBox.Value;
            options.system.bds = app.BDSCheckBox.Value;
            options.clck_int   = 30;
            options.wave(1)    =app.L1CheckBox.Value;
            options.wave(2)    =app.L2CheckBox.Value;

            startTime = tic;
            len=length(list_obs);
            for i=1:len
                files.rinex=[list_obs(i).folder '\' list_obs(i).name];

                [data] = data_hand(files,options);

                grandPath=fileparts(fileparts(folderPath));
                year=num2str(data.inf.time.first(1));
                doy=data.inf.time.doy;
                [~, fileName, ~] = fileparts(data.files.rinex);
                name=['COR_',fileName(1:4),year(3:4),num2str(doy,'%03d'),'.mat'];
                newDirectoryName = ['OBS/',year(3:4),num2str(doy,'%03d')];
                cordata_path= fullfile(grandPath, newDirectoryName);
                filepath=[cordata_path,'\',name];
 
                if isfile(filepath)&& isempty(app.Refresh)
                    load(filepath,'corrected_data');
                else
                    options.elvangle = app.cutoffangle.Value;
                    options.CSMw     = app.slip.Value;
                    options.CSGf     = app.slip.Value;
                    options.clkjump  = 1;
                    options.codsmth  = 1;
                    options.ProMod = 1;
                    % model
                    options.SatClk   = 1;
                    options.SatAPC   = 1;
                    options.SatWind  = 1;
                    options.RecAPC   = 1;
                    options.RecARP   = 1;
                    options.AtmTrop  = 1;
                    options.TroGrad  = 1;
                    options.RelClk   = 1;
                    options.RelPath  = 1;
                    options.Solid    = 1;
                    options.from     = 0;
                    options.to       = 86370;
                    [data] = preprocess(data,options);
                    [model] = nmodel(data,options);
                    corrected_data=model_cor(data,model,folderPath);
                end
                cutoff_freq = [app.cutoff_freqEditField.Value,app.cutEditField_3.Value];
                fs = 1/30;
                filtered_data=butterworthband(corrected_data,cutoff_freq,fs);
                get_sigmaphi(filtered_data,options,folderPath)
                fprintf('Completed processing sigmaphi file %d / %d: %s\n', i, len, list_obs(i).name);
            end
            app.Refresh=[];
            elapsedTime = toc(startTime);
            app.EditField_4.Value = sprintf('%.2f s', elapsedTime);

        end

        % Button pushed function: plotfileButton
        function plotfileButtonPushed(app, event)
            %Select a single generated file calculated by this software
            % including ROTI, AATR, DIXSG, SIGMAPHI. Please note that it is not an M-multiple system file
            [filename, filepath] = uigetfile({'*.mat', 'MAT Files (*.mat)'}, 'Select a MAT file');
            if ischar(filename)
            full_path = fullfile(filepath, filename);
            app.plotfile.Editable = 'on';
            app.plotfile.Value    = full_path;
            app.plotfile.Editable = 'off';
            S=Single_data(full_path);
            app.diffsateTextArea.Value=S;
            cla(app.UIAxes);
            axis(app.UIAxes, 'auto');  %Automatically set the coordinate axis range
            xlabel(app.UIAxes, '');   %Clear the x-axis label
            ylabel(app.UIAxes, '');   %Clear y-axis label
            title(app.UIAxes, '');    %Clear title
            xticks(app.UIAxes, '');
            xticklabels(app.UIAxes, '');
            app.plot_sateEditField.Value='all';
            else
                return
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Draw the selected individual file
            full_path=app.plotfile.Value;
            [~, filename, ~] = fileparts(full_path);
            last_four = filename(end-3:end);
            cla(app.UIAxes);
            legend(app.UIAxes,'off')
            if strcmp(app.plot_sateEditField.Value, 'all')
                allnum='all';
                switch last_four
                    case 'ROTI'
                        ROTI_plot(app,full_path,filename,allnum);
                    case 'AATR'
                        AATR_plot(app,full_path,filename,allnum);
                    case 'IXSG'
                        DIXSG_plot(app,full_path)
                    case 'aphi'
                        SIGMAPHI_plot(app,full_path,filename,allnum)
                    otherwise
                        app.plotfile.Value='Unknown file type';
                end
            else
                allnum=app.plot_sateEditField.Value;
                switch last_four
                    case 'ROTI'
                        ROTI_plot(app,full_path,filename,allnum);
                    case 'AATR'
                        AATR_plot(app,full_path,filename,allnum);
                    case 'IXSG'
                        DIXSG_plot(app,full_path)
                    case 'aphi'
                        SIGMAPHI_plot(app,full_path,filename,allnum)
                    otherwise
                        app.plotfile.Value='Unknown file type';
                end
            end
        end

        % Value changed function: slip
        function slipValueChanged(app, event)
            app.Refresh=1;
        end

        % Value changed function: cutoffangle
        function cutoffangleValueChanged(app, event)
            app.Refresh=1;            
        end

        % Button pushed function: XYZ2
        function XYZ2ButtonPushed(app, event)
            [file,path] = uigetfile('*.txt','Select the XYZ(.txt) file');
            app.XYZ.Value       = '';
            if ischar(file)
                xyz = fullfile(path,file);
                app.XYZ.Editable    = 'on';
                app.XYZ.Value       = xyz;
                app.XYZ.Editable    = 'off';
            else
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.M_ISSIONUIFigure = uifigure('Visible', 'off');
            app.M_ISSIONUIFigure.Name = 'M_ISSION';
            app.TabGroup = uitabgroup(app.M_ISSIONUIFigure);
            app.FactorcalculationTab = uitab(app.TabGroup);
            app.FactorcalculationTab.Title = 'Factor calculation';
            app.inputPanel = uipanel(app.FactorcalculationTab);
            app.inputPanel.Title = 'input';
            app.ObservationButton = uibutton(app.inputPanel, 'push');
            app.ObservationButton.ButtonPushedFcn = createCallbackFcn(app, @ObservationButtonPushed, true);
            app.ObservationButton.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.ObservationButton.Tooltip = {''};
            app.ObservationButton.Text = '';
            app.ObservationFileLabel = uilabel(app.inputPanel);
            app.ObservationFileLabel.Text = 'Rinex';
            app.Observation = uieditfield(app.inputPanel, 'text');
            app.Observation.Editable = 'off';
            app.Label = uilabel(app.inputPanel);
            app.Label.Text = 'Lim';
            app.cutoffangle = uieditfield(app.inputPanel, 'numeric');
            app.cutoffangle.ValueDisplayFormat = '%.0f';
            app.cutoffangle.ValueChangedFcn = createCallbackFcn(app, @cutoffangleValueChanged, true);
            app.cutoffangle.Value = 30;
            app.CycleSlipDetectionLabel = uilabel(app.inputPanel);
            app.CycleSlipDetectionLabel.Text = 'Cycle Slip Detection';
            app.slip = uiswitch(app.inputPanel, 'slider');
            app.slip.Items = {'off', 'on'};
            app.slip.ValueChangedFcn = createCallbackFcn(app, @slipValueChanged, true);
            app.slip.Value = 'on';
            app.ClockFileLabel = uilabel(app.inputPanel);
            app.ClockFileLabel.Text = 'Sp3 ';
            app.Sp3 = uieditfield(app.inputPanel, 'text');
            app.Sp3.Editable = 'off';
            app.Sp3Button = uibutton(app.inputPanel, 'push');
            app.Sp3Button.ButtonPushedFcn = createCallbackFcn(app, @Sp3ButtonPushed, true);
            app.Sp3Button.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.Sp3Button.Tooltip = {''};
            app.Sp3Button.Text = '';
            app.SystemSelectionLabel = uilabel(app.inputPanel);
            app.SystemSelectionLabel.Text = 'System Selection';
            app.GPSCheckBox = uicheckbox(app.inputPanel);
            app.GPSCheckBox.Text = 'GPS';
            app.GPSCheckBox.Value = true;
            app.GLOCheckBox = uicheckbox(app.inputPanel);
            app.GLOCheckBox.Text = 'GLO';
            app.GALCheckBox = uicheckbox(app.inputPanel);
            app.GALCheckBox.Text = 'GAL';
            app.BDSCheckBox = uicheckbox(app.inputPanel);
            app.BDSCheckBox.Text = 'BDS';
            app.ROTIAATRPanel = uipanel(app.FactorcalculationTab);
            app.ROTIAATRPanel.Title = 'ROTI &&  AATR';
            app.AATRButton = uibutton(app.ROTIAATRPanel, 'push');
            app.AATRButton.ButtonPushedFcn = createCallbackFcn(app, @AATRButtonPushed, true);
            app.AATRButton.Text = 'AATR';
            app.EditField = uieditfield(app.ROTIAATRPanel, 'text');
            app.EditField.Editable = 'off';
            app.ROTIButton = uibutton(app.ROTIAATRPanel, 'push');
            app.ROTIButton.ButtonPushedFcn = createCallbackFcn(app, @ROTIButtonPushed, true);
            app.ROTIButton.Text = 'ROTI';
            app.EditField_2 = uieditfield(app.ROTIAATRPanel, 'text');
            app.EditField_2.Editable = 'off';
            app.DIXSGPanel = uipanel(app.FactorcalculationTab);
            app.DIXSGPanel.Title = 'DIXSG';
            app.DIXSGButton = uibutton(app.DIXSGPanel, 'push');
            app.DIXSGButton.ButtonPushedFcn = createCallbackFcn(app, @DIXSGButtonPushed, true);
            app.DIXSGButton.Text = 'DIXSG';
            app.EditField_3 = uieditfield(app.DIXSGPanel, 'text');
            app.EditField_3.Editable = 'off';
            app.LatEditFieldLabel = uilabel(app.DIXSGPanel);
            app.LatEditFieldLabel.Text = ' Lat';
            app.LatEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.LatEditField.Limits = [-90 90];
            app.LatEditField.Value = -90;
            app.Label_2 = uilabel(app.DIXSGPanel);
            app.Label_2.Text = '~';
            app.latEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.latEditField.Limits = [-90 90];
            app.latEditField.Value = 90;
            app.dlatEditFieldLabel = uilabel(app.DIXSGPanel);
            app.dlatEditFieldLabel.Text = 'dlat';
            app.dlatEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.dlatEditField.Value = 1;
            app.levelEditFieldLabel = uilabel(app.DIXSGPanel);
            app.levelEditFieldLabel.Text = 'level';
            app.levelEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.levelEditField.Value = 5;
            app.firstEditFieldLabel = uilabel(app.DIXSGPanel);
            app.firstEditFieldLabel.Text = 'first';
            app.firstEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.firstEditField.Value = 100;
            app.stepEditFieldLabel = uilabel(app.DIXSGPanel);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.stepEditField.Value = 50;
            app.lonEditFieldLabel = uilabel(app.DIXSGPanel);
            app.lonEditFieldLabel.Text = 'lon';
            app.lonEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.lonEditField.Limits = [-180 180];
            app.lonEditField.Value = -180;
            app.EditField_7Label = uilabel(app.DIXSGPanel);
            app.EditField_7Label.Text = '~';
            app.lonEditField_2 = uieditfield(app.DIXSGPanel, 'numeric');
            app.lonEditField_2.Value = 180;
            app.dlonEditFieldLabel = uilabel(app.DIXSGPanel);
            app.dlonEditFieldLabel.Text = 'dlon';
            app.dlonEditField = uieditfield(app.DIXSGPanel, 'numeric');
            app.dlonEditField.Value = 0.5;
            app.SIGMAPHIPanel = uipanel(app.FactorcalculationTab);
            app.SIGMAPHIPanel.Title = 'SIGMAPHI';
            app.ObservationFileLabel_2 = uilabel(app.SIGMAPHIPanel);
            app.ObservationFileLabel_2.Text = 'CLK';
            app.Clock = uieditfield(app.SIGMAPHIPanel, 'text');
            app.Clock.Editable = 'off';
            app.CLK = uibutton(app.SIGMAPHIPanel, 'push');
            app.CLK.ButtonPushedFcn = createCallbackFcn(app, @CLKButtonPushed, true);
            app.CLK.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.CLK.Tooltip = {''};
            app.CLK.Text = '';
            app.ObservationFileLabel_3 = uilabel(app.SIGMAPHIPanel);
            app.ObservationFileLabel_3.Text = 'ATX';
            app.Antenna = uieditfield(app.SIGMAPHIPanel, 'text');
            app.Antenna.Editable = 'off';
            app.ATX = uibutton(app.SIGMAPHIPanel, 'push');
            app.ATX.ButtonPushedFcn = createCallbackFcn(app, @ATXButtonPushed, true);
            app.ATX.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.ATX.Tooltip = {''};
            app.ATX.Text = '';
            app.sigmaphiButton = uibutton(app.SIGMAPHIPanel, 'push');
            app.sigmaphiButton.ButtonPushedFcn = createCallbackFcn(app, @sigmaphiButtonPushed, true);
            app.sigmaphiButton.Text = 'sigma phi';
            app.cutoff_freqEditFieldLabel = uilabel(app.SIGMAPHIPanel);
            app.cutoff_freqEditFieldLabel.Text = 'cutoff_freq';
            app.cutoff_freqEditField = uieditfield(app.SIGMAPHIPanel, 'numeric');
            app.cutoff_freqEditField.Limits = [0 0.015];
            app.cutoff_freqEditField.Value = 0.0001;
            app.L1CheckBox = uicheckbox(app.SIGMAPHIPanel);
            app.L1CheckBox.Text = 'L1';
            app.L1CheckBox.Value = true;
            app.L2CheckBox = uicheckbox(app.SIGMAPHIPanel);
            app.L2CheckBox.Text = 'L2';
            app.EditField_4 = uieditfield(app.SIGMAPHIPanel, 'text');
            app.EditField_4.Editable = 'off';
            app.XYZ2 = uibutton(app.SIGMAPHIPanel, 'push');
            app.XYZ2.ButtonPushedFcn = createCallbackFcn(app, @XYZ2ButtonPushed, true);
            app.XYZ2.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.XYZ2.Tooltip = {''};
            app.XYZ2.Text = '';
            app.ObservationFileLabel_14 = uilabel(app.SIGMAPHIPanel);
            app.ObservationFileLabel_14.Text = 'XYZ';
            app.XYZ = uieditfield(app.SIGMAPHIPanel, 'text');
            app.XYZ.Editable = 'off';
            app.Label_5 = uilabel(app.SIGMAPHIPanel);
            app.Label_5.Text = '~';
            app.cutEditField_3 = uieditfield(app.SIGMAPHIPanel, 'numeric');
            app.cutEditField_3.Limits = [0.0001 0.015];
            app.cutEditField_3.Value = 0.015;
            app.ResultdrawingTab = uitab(app.TabGroup);
            app.ResultdrawingTab.Title = 'Result drawing';
            app.UIAxes = uiaxes(app.ResultdrawingTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.plotfileButton = uibutton(app.ResultdrawingTab, 'push');
            app.plotfileButton.ButtonPushedFcn = createCallbackFcn(app, @plotfileButtonPushed, true);
            app.plotfileButton.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.plotfileButton.Tooltip = {''};
            app.plotfileButton.Text = '';
            app.ObservationFileLabel_4 = uilabel(app.ResultdrawingTab);
            app.ObservationFileLabel_4.Text = 'file';
            app.plotfile = uieditfield(app.ResultdrawingTab, 'text');
            app.plotfile.Editable = 'off';
            app.diffsateTextAreaLabel = uilabel(app.ResultdrawingTab);
            app.diffsateTextAreaLabel.Text = 'diff-sate';
            app.diffsateTextArea = uitextarea(app.ResultdrawingTab);
            app.PlotButton = uibutton(app.ResultdrawingTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.plot_sateEditFieldLabel = uilabel(app.ResultdrawingTab);
            app.plot_sateEditFieldLabel.Text = 'plot_sate';
            app.plot_sateEditField = uieditfield(app.ResultdrawingTab, 'text');
            app.plot_sateEditField.Value = 'all';
            app.comparativeanalysisTab = uitab(app.TabGroup);
            app.comparativeanalysisTab.Title = 'comparative analysis';
            app.TabGroup2 = uitabgroup(app.comparativeanalysisTab);
            app.MultisystemTab = uitab(app.TabGroup2);
            app.MultisystemTab.Title = 'Multisystem ';
            app.Panel = uipanel(app.MultisystemTab);
            app.UIAxes6 = uiaxes(app.Panel);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.PlotButton_3 = uibutton(app.MultisystemTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.plotfileButton_2 = uibutton(app.MultisystemTab, 'push');
            app.plotfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotfileButton_2Pushed, true);
            app.plotfileButton_2.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.plotfileButton_2.Tooltip = {''};
            app.plotfileButton_2.Text = '';
            app.ObservationFileLabel_5 = uilabel(app.MultisystemTab);
            app.ObservationFileLabel_5.Text = 'M_file';
            app.plotfile_2 = uieditfield(app.MultisystemTab, 'text');
            app.plotfile_2.Editable = 'off';
            app.HEditFieldLabel = uilabel(app.MultisystemTab);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.MultisystemTab, 'numeric');
            app.HEditField.Limits = [0 23];
            app.MEditFieldLabel = uilabel(app.MultisystemTab);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.MultisystemTab, 'numeric');
            app.MEditField.Limits = [0 60];
            app.MEditFieldLabel_2 = uilabel(app.MultisystemTab);
            app.MEditFieldLabel_2.Text = 'M';
            app.MEditField_2 = uieditfield(app.MultisystemTab, 'numeric');
            app.MEditField_2.Limits = [0 60];
            app.MEditField_2.Value = 60;
            app.HEditField_2Label = uilabel(app.MultisystemTab);
            app.HEditField_2Label.Text = 'H';
            app.HEditField_2 = uieditfield(app.MultisystemTab, 'numeric');
            app.HEditField_2.Limits = [0 23];
            app.HEditField_2.Value = 23;
            app.Label_3 = uilabel(app.MultisystemTab);
            app.Label_3.Text = '~';
            app.GLampLabel = uilabel(app.MultisystemTab);
            app.GLampLabel.Text = 'G';
            app.GLamp = uilamp(app.MultisystemTab);
            app.RLampLabel = uilabel(app.MultisystemTab);
            app.RLampLabel.Text = 'R';
            app.RLamp = uilamp(app.MultisystemTab);
            app.ELampLabel = uilabel(app.MultisystemTab);
            app.ELampLabel.Text = 'E';
            app.ELamp = uilamp(app.MultisystemTab);
            app.CLampLabel = uilabel(app.MultisystemTab);
            app.CLampLabel.Text = 'C';
            app.CLamp = uilamp(app.MultisystemTab);
            app.CheckBox = uicheckbox(app.MultisystemTab);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.MultisystemTab);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.MultisystemTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.MultisystemTab);
            app.CheckBox_4.Text = '';
            app.CheckBox_4.Value = true;
            app.MultipleindexTab = uitab(app.TabGroup2);
            app.MultipleindexTab.Title = 'Multiple index';
            app.PlotButton_2 = uibutton(app.MultipleindexTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.Panel_2 = uipanel(app.MultipleindexTab);
            app.UIAxes6_2 = uiaxes(app.Panel_2);
            title(app.UIAxes6_2, 'Title')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.ObservationFileLabel_6 = uilabel(app.MultipleindexTab);
            app.ObservationFileLabel_6.Text = 'ROTI';
            app.plotfile_3 = uieditfield(app.MultipleindexTab, 'text');
            app.plotfile_3.Editable = 'off';
            app.ObservationFileLabel_7 = uilabel(app.MultipleindexTab);
            app.ObservationFileLabel_7.Text = 'AATR';
            app.plotfile_4 = uieditfield(app.MultipleindexTab, 'text');
            app.plotfile_4.Editable = 'off';
            app.ObservationFileLabel_8 = uilabel(app.MultipleindexTab);
            app.ObservationFileLabel_8.Text = 'RMSAATR';
            app.plotfile_5 = uieditfield(app.MultipleindexTab, 'text');
            app.plotfile_5.Editable = 'off';
            app.ObservationFileLabel_9 = uilabel(app.MultipleindexTab);
            app.ObservationFileLabel_9.Text = 'SIGMAPHI';
            app.plotfile_6 = uieditfield(app.MultipleindexTab, 'text');
            app.plotfile_6.Editable = 'off';
            app.plotfileButton_3 = uibutton(app.MultipleindexTab, 'push');
            app.plotfileButton_3.ButtonPushedFcn = createCallbackFcn(app, @plotfileButton_3Pushed, true);
            app.plotfileButton_3.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.plotfileButton_3.Tooltip = {''};
            app.plotfileButton_3.Text = '';
            app.plotfileButton_4 = uibutton(app.MultipleindexTab, 'push');
            app.plotfileButton_4.ButtonPushedFcn = createCallbackFcn(app, @plotfileButton_4Pushed, true);
            app.plotfileButton_4.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.plotfileButton_4.Tooltip = {''};
            app.plotfileButton_4.Text = '';
            app.plotfileButton_5 = uibutton(app.MultipleindexTab, 'push');
            app.plotfileButton_5.ButtonPushedFcn = createCallbackFcn(app, @plotfileButton_5Pushed, true);
            app.plotfileButton_5.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.plotfileButton_5.Tooltip = {''};
            app.plotfileButton_5.Text = '';
            app.plotfileButton_6 = uibutton(app.MultipleindexTab, 'push');
            app.plotfileButton_6.ButtonPushedFcn = createCallbackFcn(app, @plotfileButton_6Pushed, true);
            app.plotfileButton_6.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.plotfileButton_6.Tooltip = {''};
            app.plotfileButton_6.Text = '';
            app.Button = uibutton(app.MultipleindexTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'R-C.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.MultipleindexTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'R-C.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.MultipleindexTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'R-C.png');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.MultipleindexTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'R-C.png');
            app.Button_4.Text = '';
            app.RegionaldataanalysisTab = uitab(app.TabGroup2);
            app.RegionaldataanalysisTab.Title = 'Regional data analysis';
            app.ROTIregionaldatacalculationPanel = uipanel(app.RegionaldataanalysisTab);
            app.ROTIregionaldatacalculationPanel.Title = 'ROTI regional data calculation';
            app.RUNButton = uibutton(app.ROTIregionaldatacalculationPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.EditField_5 = uieditfield(app.ROTIregionaldatacalculationPanel, 'text');
            app.EditField_5.Editable = 'off';
            app.LatEditField_2Label = uilabel(app.ROTIregionaldatacalculationPanel);
            app.LatEditField_2Label.Text = ' Lat';
            app.LatEditField_2 = uieditfield(app.ROTIregionaldatacalculationPanel, 'numeric');
            app.LatEditField_2.Limits = [-90 90];
            app.LatEditField_2.Value = -90;
            app.Label_4 = uilabel(app.ROTIregionaldatacalculationPanel);
            app.Label_4.Text = '~';
            app.latEditField_2 = uieditfield(app.ROTIregionaldatacalculationPanel, 'numeric');
            app.latEditField_2.Limits = [-90 90];
            app.latEditField_2.Value = 90;
            app.dlatEditField_2Label = uilabel(app.ROTIregionaldatacalculationPanel);
            app.dlatEditField_2Label.Text = 'dlat';
            app.dlatEditField_2 = uieditfield(app.ROTIregionaldatacalculationPanel, 'numeric');
            app.dlatEditField_2.Value = 1;
            app.lonEditField_3Label = uilabel(app.ROTIregionaldatacalculationPanel);
            app.lonEditField_3Label.Text = 'lon';
            app.lonEditField_3 = uieditfield(app.ROTIregionaldatacalculationPanel, 'numeric');
            app.lonEditField_3.Limits = [-180 180];
            app.lonEditField_3.Value = -180;
            app.EditField_7Label_2 = uilabel(app.ROTIregionaldatacalculationPanel);
            app.EditField_7Label_2.Text = '~';
            app.lonEditField_4 = uieditfield(app.ROTIregionaldatacalculationPanel, 'numeric');
            app.lonEditField_4.Value = 180;
            app.dlonEditField_2Label = uilabel(app.ROTIregionaldatacalculationPanel);
            app.dlonEditField_2Label.Text = 'dlon';
            app.dlonEditField_2 = uieditfield(app.ROTIregionaldatacalculationPanel, 'numeric');
            app.dlonEditField_2.Value = 0.5;
            app.ObservationButton_2 = uibutton(app.ROTIregionaldatacalculationPanel, 'push');
            app.ObservationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ObservationButton_2Pushed, true);
            app.ObservationButton_2.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.ObservationButton_2.Tooltip = {''};
            app.ObservationButton_2.Text = '';
            app.ObservationFileLabel_10 = uilabel(app.ROTIregionaldatacalculationPanel);
            app.ObservationFileLabel_10.Text = 'ROTI folder';
            app.Observation_2 = uieditfield(app.ROTIregionaldatacalculationPanel, 'text');
            app.Observation_2.Editable = 'off';
            app.DIXSGROTIregionaldataplottingPanel = uipanel(app.RegionaldataanalysisTab);
            app.DIXSGROTIregionaldataplottingPanel.Title = 'DIXSG && ROTI regional data plotting';
            app.PLOTButton = uibutton(app.DIXSGROTIregionaldataplottingPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.ObservationButton_3 = uibutton(app.DIXSGROTIregionaldataplottingPanel, 'push');
            app.ObservationButton_3.ButtonPushedFcn = createCallbackFcn(app, @ObservationButton_3Pushed, true);
            app.ObservationButton_3.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.ObservationButton_3.Tooltip = {''};
            app.ObservationButton_3.Text = '';
            app.ObservationFileLabel_11 = uilabel(app.DIXSGROTIregionaldataplottingPanel);
            app.ObservationFileLabel_11.Text = 'Regional DIXSG';
            app.Observation_3 = uieditfield(app.DIXSGROTIregionaldataplottingPanel, 'text');
            app.Observation_3.Editable = 'off';
            app.ObservationFileLabel_13 = uilabel(app.DIXSGROTIregionaldataplottingPanel);
            app.ObservationFileLabel_13.Text = 'Regional ROTI';
            app.Observation_5 = uieditfield(app.DIXSGROTIregionaldataplottingPanel, 'text');
            app.Observation_5.Editable = 'off';
            app.ObservationButton_5 = uibutton(app.DIXSGROTIregionaldataplottingPanel, 'push');
            app.ObservationButton_5.ButtonPushedFcn = createCallbackFcn(app, @ObservationButton_5Pushed, true);
            app.ObservationButton_5.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'icon-folder-128.png');
            app.ObservationButton_5.Tooltip = {''};
            app.ObservationButton_5.Text = '';
            app.TheEditFieldLabel = uilabel(app.DIXSGROTIregionaldataplottingPanel);
            app.TheEditFieldLabel.Text = 'The';
            app.TheEditField = uieditfield(app.DIXSGROTIregionaldataplottingPanel, 'numeric');
            app.TheEditField.Limits = [1 24];
            app.TheEditField.Value = 1;
            app.TheEditFieldLabel_2 = uilabel(app.DIXSGROTIregionaldataplottingPanel);
            app.TheEditFieldLabel_2.Text = 'th hour';
            app.Button_5 = uibutton(app.DIXSGROTIregionaldataplottingPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'R-C.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.DIXSGROTIregionaldataplottingPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'code_Data_processing', 'Icons', 'R-C.png');
            app.Button_6.Text = '';
            app.M_ISSIONLabel = uilabel(app.M_ISSIONUIFigure);
            app.M_ISSIONLabel.Text = 'M_ISSION';
            % Show the figure after all components are created
            app.M_ISSIONUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M_ISSION

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.M_ISSIONUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.M_ISSIONUIFigure)
        end
    end
end